classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        Panel                  matlab.ui.container.Panel
        Panel_2                matlab.ui.container.Panel
        errorLabel             matlab.ui.control.Label
        CalcularButton         matlab.ui.control.Button
        CALCULARMAGNITUDCAMPOELCTRICOLabel  matlab.ui.control.Label
        MedidaDropDown_3       matlab.ui.control.DropDown
        MedidaDropDown_3Label  matlab.ui.control.Label
        ValorQ3EditField       matlab.ui.control.NumericEditField
        ValorQ3EditFieldLabel  matlab.ui.control.Label
        MedidaDropDown_2       matlab.ui.control.DropDown
        MedidaDropDown_2Label  matlab.ui.control.Label
        ValorQ2EditField       matlab.ui.control.NumericEditField
        ValorQ2EditFieldLabel  matlab.ui.control.Label
        MedidaDropDown         matlab.ui.control.DropDown
        MedidaDropDownLabel    matlab.ui.control.Label
        ValorQ1EditField       matlab.ui.control.NumericEditField
        ValorQ1EditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function[q] = calcularMedida(app,q,medida)
            switch(medida)
                case("C")
                    q = q*1;
                case("μC")
                    q = q * 1*10^-6;
                case("nC")
                    q = q * 1*10^-9;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % El triangulo tiene lado a de 20 centimetros
            a = 20;
            q1 = app.ValorQ1EditField.Value;
            medidaQ1 = app.MedidaDropDown.Value;
            q1 = app.calcularMedida(q1,medidaQ1);
            q2 = app.ValorQ2EditField.Value;
            medidaQ2 = app.MedidaDropDown_2.Value;
            q2 = app.calcularMedida(q2,medidaQ2);
            q3 = app.ValorQ3EditField.Value;
            medidaQ3 = app.MedidaDropDown_3.Value;
            q3 = app.calcularMedida(q3,medidaQ3);
            graficarP(q1,q2,q3,a);
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel_2 = uipanel(app.Panel);
            app.ValorQ1EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ1EditFieldLabel.Text = 'Valor Q1';
            app.ValorQ1EditField = uieditfield(app.Panel_2, 'numeric');
            app.MedidaDropDownLabel = uilabel(app.Panel_2);
            app.MedidaDropDownLabel.Text = 'Medida';
            app.MedidaDropDown = uidropdown(app.Panel_2);
            app.MedidaDropDown.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown.Value = 'C';
            app.ValorQ2EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ2EditFieldLabel.Text = 'Valor Q2';
            app.ValorQ2EditField = uieditfield(app.Panel_2, 'numeric');
            app.ValorQ2EditField.Limits = [-Inf 0];
            app.MedidaDropDown_2Label = uilabel(app.Panel_2);
            app.MedidaDropDown_2Label.Text = 'Medida';
            app.MedidaDropDown_2 = uidropdown(app.Panel_2);
            app.MedidaDropDown_2.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_2.Value = 'C';
            app.ValorQ3EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ3EditFieldLabel.Text = 'Valor Q3';
            app.ValorQ3EditField = uieditfield(app.Panel_2, 'numeric');
            app.MedidaDropDown_3Label = uilabel(app.Panel_2);
            app.MedidaDropDown_3Label.Text = 'Medida';
            app.MedidaDropDown_3 = uidropdown(app.Panel_2);
            app.MedidaDropDown_3.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_3.Value = 'C';
            app.CALCULARMAGNITUDCAMPOELCTRICOLabel = uilabel(app.Panel_2);
            app.CALCULARMAGNITUDCAMPOELCTRICOLabel.Text = 'CALCULAR MAGNITUD CAMPO ELÉCTRICO';
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.errorLabel = uilabel(app.Panel_2);
            app.errorLabel.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'images.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end